classdef INTERFAZ_V6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image6                          matlab.ui.control.Image
        PanelModoManual                 matlab.ui.container.Panel
        ATERRIZADOLamp                  matlab.ui.control.Lamp
        ATERRIZADOLampLabel             matlab.ui.control.Label
        ATERRIZARButton                 matlab.ui.control.StateButton
        PitchEditField                  matlab.ui.control.NumericEditField
        pitchLabel                      matlab.ui.control.Label
        SliderPitch                     matlab.ui.control.Slider
        ModoSwitch                      matlab.ui.control.Switch
        ModoSwitchLabel                 matlab.ui.control.Label
        TravelEditField                 matlab.ui.control.NumericEditField
        TravelEditFieldLabel            matlab.ui.control.Label
        ElevationEditField              matlab.ui.control.NumericEditField
        ElevationEditFieldLabel         matlab.ui.control.Label
        SliderElevation                 matlab.ui.control.Slider
        Image7                          matlab.ui.control.Image
        MasterSwitch                    matlab.ui.control.Switch
        MotorFGaugeLabel                matlab.ui.control.Label
        MotorBGaugeLabel                matlab.ui.control.Label
        MotorBGauge                     matlab.ui.control.LinearGauge
        MotorFGauge                     matlab.ui.control.LinearGauge
        Titulo                          matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        SETPOINTSLabel                  matlab.ui.control.Label
        ESP_Global                      matlab.ui.control.Label
        PSP_Global                      matlab.ui.control.Label
        TSP_Global                      matlab.ui.control.Label
        GRFICASACCIONESDECONTROLButton  matlab.ui.control.Button
        GRFICASVARIABLESButton          matlab.ui.control.Button
        ElevationLabel                  matlab.ui.control.Label
        ElevationGaugeLabel             matlab.ui.control.Label
        ElevationGauge                  matlab.ui.control.Gauge
        TravelLabel                     matlab.ui.control.Label
        TravelGaugeLabel                matlab.ui.control.Label
        TravelGauge                     matlab.ui.control.Gauge
        PitchLabel                      matlab.ui.control.Label
        PitchGauge                      matlab.ui.control.Gauge
        PitchGaugeLabel                 matlab.ui.control.Label
        HomeButton                      matlab.ui.control.Button
        MODOMYOLamp                     matlab.ui.control.Lamp
        MODOMYOLampLabel                matlab.ui.control.Label
        DETENERButton                   matlab.ui.control.Button
        INICIARButton                   matlab.ui.control.Button
        PanelModoMyo                    matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        ImageWaveOut                    matlab.ui.control.Image
        ImageWaveIn                     matlab.ui.control.Image
        ImageFist                       matlab.ui.control.Image
        ImageOpen                       matlab.ui.control.Image
        ImagePinch                      matlab.ui.control.Image
        MODOIMULampLabel                matlab.ui.control.Label
        MODOIMULamp                     matlab.ui.control.Lamp
        ORIGENButton                    matlab.ui.control.Button
        LabelGesto                      matlab.ui.control.Label
        ESP_IMU                         matlab.ui.control.Label
        PSP_IMU                         matlab.ui.control.Label
        PspLabel                        matlab.ui.control.Label
        EspLabel                        matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        Orientation                     matlab.ui.control.UIAxes
        Image3                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        tick    %variable para mantener registro del paso del tiempo
        u       %Puerto UDP para mandar datos desde el otro matlab
        RTO     %Variable para guardar el RTO
        HGR     %Variable para guardar el valor del gesto
        E_SP    %Valor de Setpoint para Elevation
        T_SP    %Valor de Setpoint para Travel
        RUN     %Banderita para correr la interfaz cuando se aplasta el boton
        Myo     %Datos del Myo para sacar lo de la IMU
        m       %Variable para que funcione lo de Myo
        MODO    %Variable para realizar modo gesto o IMU
        theta0  %Origen del movimiento theta (mueve elevation)
        psi0    %Origen del movimiento psi (mueve travel)
        theta   %Variable theta
        psi     %Variable psi
        
        %Vectores para plots:
        logPsi
        logTheta
        
        contadorWO  %Son variables para hacer funcionar los gestos porque se detectan varias veces
        contadorWI
        contadorOpen
        contadorFist
        contadorPinch
        NoGestureFlag   %Variable para dar paso a que se ejecuten las acciones
        
        aterrizaje
    end
    
    methods (Access = private)
        function CallbackSIM(app) 
            Travel = app.RTO.OutputPort(1).Data(1);
            Elevation = app.RTO.OutputPort(1).Data(2);
            Pitch = app.RTO.OutputPort(1).Data(3);
            Mf = app.RTO.OutputPort(1).Data(4);
            Mb = app.RTO.OutputPort(1).Data(5);
            TSP = app.RTO.OutputPort(1).Data(6);
            ESP = app.RTO.OutputPort(1).Data(7);
            PSP = app.RTO.OutputPort(1).Data(8);
            % Mostrar en displays y relojes:
            app.TravelLabel.Text = num2str(Travel, '%0.2f');
            app.ElevationLabel.Text = num2str(Elevation, '%0.2f');
            app.PitchLabel.Text = num2str(Pitch, '%0.2f');
            %Relojito Pitch
            app.PitchGauge.Value = Pitch;
            %Reloj Elevation
            app.ElevationGauge.Value = Elevation;
            %Correccion para mostrar en el relojito TRAVEL desde -180 a 180
            var = mod(Travel, 360);
            if var>180
                app.TravelGauge.Value = var - 360;
            elseif var < -180
                app.TravelGauge.Value = var + 360;
            else
                app.TravelGauge.Value = var;
            end
            %Barras Motores:
            app.MotorBGauge.Value = Mb;
            app.MotorFGauge.Value = Mf;
        end
        
        %CALLBACK PARA EL EVENTO DE QUE HAYAN BYTES DISPONIBLES EN app.u
        function CallbackHGR(app)
            app.HGR = read(app.u, 1);
            flush(app.u)
            switch app.HGR
                case 1
                    app.LabelGesto.Text = 'Wave Out';       
                    app.ImageWaveOut.Visible = true;    %Solo WaveOut
                    app.ImageWaveIn.Visible = false;
                    app.ImageFist.Visible = false;
                    app.ImageOpen.Visible = false;
                    app.ImagePinch.Visible = false;
                    app.contadorWO = app.contadorWO + 1;     %Aumenta Travel
                case 2
                    app.LabelGesto.Text = 'Wave In';         
                    app.ImageWaveOut.Visible = false;   
                    app.ImageWaveIn.Visible = true;     %Solo WaveIn
                    app.ImageFist.Visible = false;
                    app.ImageOpen.Visible = false;
                    app.ImagePinch.Visible = false;
                    app.contadorWI = app.contadorWI + 1;    %Disminuye Travel 
                case 3
                    app.LabelGesto.Text = 'Fist';
                    app.ImageWaveOut.Visible = false;   
                    app.ImageWaveIn.Visible = false;
                    app.ImageFist.Visible = true;       %Solo Fist
                    app.ImageOpen.Visible = false;
                    app.ImagePinch.Visible = false;
                    app.contadorFist = app.contadorFist + 1;    %Cambiar MODO
                case 4
                    app.LabelGesto.Text = 'Open';
                    app.ImageWaveOut.Visible = false;   
                    app.ImageWaveIn.Visible = false;
                    app.ImageFist.Visible = false;                            
                    app.ImageOpen.Visible = true;       %Solo Open
                    app.ImagePinch.Visible = false;
                    app.contadorOpen = app.contadorOpen + 1;    %Despega
                case 5
                    app.LabelGesto.Text = 'Pinch';
                    app.ImageWaveOut.Visible = false;  
                    app.ImageWaveIn.Visible = false;
                    app.ImageFist.Visible = false;
                    app.ImageOpen.Visible = false;
                    app.ImagePinch.Visible = true;      
                    app.contadorPinch = app.contadorPinch + 1;    %Aterriza
                case 6
                    app.LabelGesto.Text = 'No Gesto';
                    app.ImageWaveOut.Visible = false;   %Ninguno
                    app.ImageWaveIn.Visible = false;
                    app.ImageFist.Visible = false;
                    app.ImageOpen.Visible = false;
                    app.ImagePinch.Visible = false;
                    app.NoGestureFlag = true;   %Doy paso a que se ejecuten las acciones
                otherwise
            end
            
            if app.MasterSwitch.Value == "On" %Si esta activado el modo Myo:
                if ~app.aterrizaje  %Solo hace si no esta en aterrizaje: moverse a la izquierda, derecha, cambiar modo y obviamente aterrizar
                    %Accion WaveOut: disminuye 45
                    if app.contadorWO >= 2
                        if app.MODO == "GESTO"  %Solo hace si esta en modo gesto
                            app.T_SP = str2double(get_param('Conexion_Inalambrica/Travel', 'Value')) - 45;  %LEO EL VALOR y LUEGO le resto 45
                            set_param('Conexion_Inalambrica/Travel', 'Value', num2str(app.T_SP))
                        end
                        app.contadorWO = 0;
                    end
                    %Accion WaveIn: aumenta 45
                    if app.contadorWI >= 2
                        if app.MODO == "GESTO"  %Solo hace si esta en modo gesto
                            app.T_SP = str2double(get_param('Conexion_Inalambrica/Travel', 'Value')) + 45;  %LEO EL VALOR y LUEGO le sumo 45
                            set_param('Conexion_Inalambrica/Travel', 'Value', num2str(app.T_SP))
                        end
                        app.contadorWI = 0;
                    end
                    %Accion Pinch: aterriza
                    if app.contadorPinch >= 1
                        app.contadorPinch = 0;
                        app.aterrizaje = true;
                        set_param('Conexion_Inalambrica/Elevation', 'Value', "-30")
                    end 
                    %Accion Fist: Cambia Modo
                    if app.contadorFist >= 2
                        if app.MODO == "GESTO" 
                            app.MODO = "IMU"; %CAMBIO DE MODO GESTO A MODO IMU
                            app.MODOIMULamp.Enable = true;
                            set_param('Conexion_Inalambrica/Elevation', 'Value', "0")   %Empieza elevation en 0
                            set_param('Conexion_Inalambrica/Modo', 'Value', "1")    %Cambia a modo IMU = 1
                        else %app.MODO == "IMU"
                            app.MODO = "GESTO"; %CAMBIO DE MODO IMU A MODO GESTO
                            app.MODOIMULamp.Enable = false;
                            set_param('Conexion_Inalambrica/Elevation', 'Value', "0")   %Regresa elevation en 0
                            
                            %(para que no se vuelva loco)
                            set_param('Conexion_Inalambrica/Pitch', 'Value', "0")       %Mando 0 al pitch para que quede quieto
                            %Y por ultimo:
                            set_param('Conexion_Inalambrica/Modo', 'Value', "0")    %Cambia a modo gesto = 0
                            %Muestro 0 en los Labels:
                            app.ESP_IMU.Text = "0";
                            app.PSP_IMU.Text = "0";
                        end
                        app.contadorFist = 0;
                    end
                end
                % Lo unico que puede hacer cuando no esta en no aterrizaje es
                % despegar:
                
                %Accion Open: despega
                if app.contadorOpen >= 2
                    app.contadorOpen = 0;
                    app.aterrizaje = false;
                    set_param('Conexion_Inalambrica/Elevation', 'Value', "0")
                end   
            end
        end
        
        %CALLBACK PARA EJECUTAR LO DE MODO IMU
        function CallbackIMU(app)
            %Modificacion: mejor que E_SP aumente si app.psi pasa de cierto
            %valor (p.ej. 20°) en lugar de:
            %app.E_SP = app.E_SP + 0.05*app.psi;
            
            if app.theta >= 20
                app.E_SP = app.E_SP + 0.5;  %Si el brazo esta encima de 20 aumenta con delta = 0.5
            end
            if app.theta <= -20
                app.E_SP = app.E_SP - 0.5;  %Si el brazo esta debajo de -20 disminuye con delta = -0.5
            end
            
            if app.E_SP>=30 %Saturacion
                app.E_SP = 30;
            elseif app.E_SP <= -15
                app.E_SP = -15;
            end
                       
            app.ESP_IMU.Text = num2str(app.E_SP);
            app.PSP_IMU.Text = num2str(0.3*app.psi);
            
            set_param('Conexion_Inalambrica/Elevation', 'Value', num2str(app.E_SP))
            set_param('Conexion_Inalambrica/Pitch', 'Value', num2str(0.3*app.psi))
            ultimoT = app.RTO.OutputPort(1).Data(1);    %Leo el ultimo valor que este en TRAVEL
            set_param('Conexion_Inalambrica/Travel', 'Value', num2str(ultimoT))   %Mueve a TravelSP el ultimo valor que haya quedado
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.E_SP = 0;   %Variables de setpoint
            app.T_SP = 0;
            app.contadorWI = 0;
            app.contadorWO = 0;
            app.contadorOpen = 0;
            app.contadorFist = 0;
            app.contadorPinch = 0;
            app.NoGestureFlag = false;  %Solo cuando sea true se pueden hacer las acciones
            %Sockets UDP:
            app.u = udpport('LocalHost','127.0.0.1','LocalPort', 2021); %Declaracion del puerto UDP 2021
            %Cargar el Simulink
            load_system('Conexion_Inalambrica.slx')
            %Inicializar el tick
            app.tick = 1;   
            %Variables para mostrar
            app.logTheta = 0;
            app.logPsi = 0;
            %Inicializar IMU
            app.theta = 0;
            app.theta0 = 0;
            app.psi = 0;
            app.psi0 = 0;
            %Colores de los axes:
            set(app.Orientation, 'color', [0.10,0.13,0.16]) 
            legend(app.Orientation, {'Vertical', 'Horizontal'}, 'TextColor', [0.35,0.74,0.80]);
            %Ejecucion de la interfaz: NO
            app.RUN = false;
            app.aterrizaje = false;
            %Inicializacion del Myo
            %%%%%COMENTADO PARA PRUEBAS X10000
            app.m = MyoMex(1);
            app.Myo = app.m.myoData(1);
            app.Myo.stopStreaming();
            app.m.myoData.clearLogs();
            app.Myo.startStreaming();
            %Inicia en modo GESTO
            app.MODO = "GESTO";
            set_param('Conexion_Inalambrica/Modo', 'Value', "0")    %Inicia en modo GESTO = 0
            app.MODOIMULamp.Enable = false;
            
            %Encerar los setpoints
            set_param('Conexion_Inalambrica/Elevation', 'Value', '0')
            set_param('Conexion_Inalambrica/Travel', 'Value', '0')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.RUN = false;
            set_param('Conexion_Inalambrica', 'SimulationCommand', 'Stop'); %detiene la ejecucion del modelo en Simulink
            %Vaciar y borrar el puerto udp
            flush(app.u)
            clear app.u
            %Borrar las cosas de Myo
            %%%%%COMENTADO PARA PRUEBAS X10000
            app.Myo.stopStreaming();    %Stop streaming Myo_1 all data
            app.m.myoData.clearLogs(); 
            app.m.delete;              %Delete variable for Myo_1   
            clear app.m                %Clear variable for Myo_1
            %Borrar la app
            delete(app)
        end

        % Button pushed function: INICIARButton
        function CorrerLazo(app, event)
            app.RUN = true; %Cuando esto es false deja de mostrar cosas
            set_param('Conexion_Inalambrica', 'SimulationCommand', 'continue')
            %Se debe pausar la simulacion antes de empezar la interfaz
            %------------------------LAZO-----------------------------
            while app.RUN            
                pause(0.3)    %esta pausita es muy importante 0.25 funciona bien
                %=============================================================
                %Angulos de euler theta y psi - Calculados con el
                %giroscopio
                app.theta = (180/pi)*(asin(2*(app.Myo.quat(1)*app.Myo.quat(3) - app.Myo.quat(4)*app.Myo.quat(2))) - app.theta0);   %No olvides restar el theta0
                app.psi = (180/pi)*(atan2(2*(app.Myo.quat(1)*app.Myo.quat(4) + app.Myo.quat(2)*app.Myo.quat(3)), 1-...
                    2*(app.Myo.quat(3)^2 + app.Myo.quat(4)^2)) - app.psi0);      %No olvides restar el psi0
                app.logTheta(app.tick) = app.theta;
                app.logPsi(app.tick) = app.psi;
                
                set_param('Conexion_Inalambrica/IMU_H', 'Value', num2str(app.theta)); %Para visualizar el valor de la imu horizontal: theta
                set_param('Conexion_Inalambrica/IMU_V', 'Value', num2str(app.psi)); %Para visualizar el valor de la imu vertical: psi
                
                %Espacio para plotear las variables:
                if app.tick > 500
                    app.tick = 1;
                    %Importante: Cada vez que se encera el timer
                    app.logTheta = 0;
                    app.logPsi = 0;
                    plot(app.Orientation, app.logTheta);
                    hold(app.Orientation, 'on');    %Para dibujar las otras variables:
                    plot(app.Orientation, app.logPsi);
                    hold(app.Orientation, 'off');
                end            
                if app.tick <= 100
                    plot(app.Orientation, app.logTheta);
                    hold(app.Orientation, 'on');    %Para dibujar la otra variable:
                    plot(app.Orientation, app.logPsi);
                    hold(app.Orientation, 'off');
                else               
                    plot(app.Orientation, app.logTheta(app.tick-100:app.tick));
                    hold(app.Orientation, 'on');    %Para dibujar la otra variable:
                    plot(app.Orientation, app.logPsi(app.tick-100:app.tick));
                    hold(app.Orientation, 'off');
                end
                legend(app.Orientation, {'Vertical', 'Horizontal'}, 'TextColor', [0.35,0.74,0.80], 'Orientation','vertical');
                
                app.tick = app.tick + 1;
                %app.Display.Text = num2str(app.tick);
                %=============================================================
                
                if get_param('Conexion_Inalambrica', 'SimulationStatus') == "running"
                    app.RTO = get_param('Conexion_Inalambrica/Master_G', 'RuntimeObject');
                    app.CallbackSIM();
                end
                %%
                if app.MasterSwitch.Value == "On"   %Ejecuto lo de Myo solamente si esta activado para MYO, caso contrario es mediante la interfaz, haciendo con sliders
                    app.MODOMYOLamp.Enable = true;  %Enciende la luz y desactiva las entradas manuales
                    app.ElevationEditField.Enable = false;
                    app.TravelEditField.Enable = false;
                    app.SliderElevation.Enable = false;
                    app.SliderPitch.Enable = false;
                    %%
                    if app.u.NumBytesAvailable
                        app.CallbackHGR();                    
                    end
                    %%
                    if app.MODO == "IMU"
                        app.CallbackIMU();
                    end
                end
                
                if app.MasterSwitch.Value == "Off"  %Modo manual:
                    app.MODOMYOLamp.Enable = false; %Apaga la luz y activa las entradas manuales
                    app.ElevationEditField.Enable = true;
                    app.TravelEditField.Enable = true;
                    app.SliderElevation.Enable = true;
                    app.SliderPitch.Enable = true;
                    %%
                    %Las modificaciones de los setpoints y todo eso está en
                    %los respectivos callbacks de los elementos
                    %correspondientes: SliderElevation, SliderTravel,
                    %ElevationEditField, TravelEditField
                    if app.ModoSwitch.Value == "Control Pitch" %Si esta en modo Pitch debe copiar en el TravelEditField el valor de Travel
                        app.TravelEditField.Enable = false;
                        ultimoT = app.RTO.OutputPort(1).Data(1);    %Leo el ultimo valor que este en TRAVEL
                        app.TravelEditField.Value = ultimoT;    %Copio en el texto este valor
                        ultimoT = app.RTO.OutputPort(1).Data(1);    %Leo el ultimo valor que este en TRAVEL
                        set_param('Conexion_Inalambrica/Travel', 'Value', num2str(ultimoT))   %Mueve a TravelSP el ultimo valor que haya quedado
                    end
                end
                
                %Mostrar los valores de setpoints:
                app.TSP_Global.Text = get_param('Conexion_Inalambrica/Travel', 'Value');
                app.ESP_Global.Text = get_param('Conexion_Inalambrica/Elevation', 'Value');
                app.PSP_Global.Text = num2str(app.RTO.OutputPort(1).Data(8));   %Recuerda que esto es PSP.
            end
            %----------------------FIN LAZO---------------------------
        end

        % Button pushed function: DETENERButton
        function DetenerLazo(app, event)
            set_param('Conexion_Inalambrica', 'SimulationCommand', 'pause'); %solo PAUSA la ejecucion del modelo en Simulink
            app.RUN = false;
        end

        % Button pushed function: GRFICASVARIABLESButton
        function DisplayVariables(app, event)
            open_system('Conexion_Inalambrica/Variables');
        end

        % Button pushed function: GRFICASACCIONESDECONTROLButton
        function DisplayControl(app, event)
            open_system('Conexion_Inalambrica/Acciones de Control');
        end

        % Button pushed function: ORIGENButton
        function EncerarOrientacion(app, event)
%             app.psi = 0;
%             app.theta = 0;
            %En caso de usar los quats solo se debe calcular el t0 y p0 y nada mas
            app.theta0 = asin(2*(app.Myo.quat(1)*app.Myo.quat(3) - app.Myo.quat(4)*app.Myo.quat(2)));  
            app.psi0 = atan2(2*(app.Myo.quat(1)*app.Myo.quat(4) + app.Myo.quat(2)*app.Myo.quat(3)), 1-...
                2*(app.Myo.quat(3)^2 + app.Myo.quat(4)^2));
            
        end

        % Value changed function: ModoSwitch
        function CambioModoEnManual(app, event)
            valueMODO = app.ModoSwitch.Value;
            if get_param('Conexion_Inalambrica/Modo', 'Value') == "0"   %Leo y cambio: le hago un TOGGLE
                set_param('Conexion_Inalambrica/Modo', 'Value', "1")
            elseif get_param('Conexion_Inalambrica/Modo', 'Value') == "1"   %TOGGLE
                set_param('Conexion_Inalambrica/Modo', 'Value', "0")
            end
            if valueMODO == "Control Travel"
                app.PitchEditField.Enable = false;  %Deshabilito lo de pitch para dejar solo travel
                app.SliderPitch.Enable = false;
                app.TravelEditField.Enable = true;
            else
                app.PitchEditField.Enable = true;  %Habilito los de pitch
                app.SliderPitch.Enable = true;
                app.TravelEditField.Enable = false;
            end
        end

        % Value changed function: SliderElevation
        function ElevationSliderChange(app, event)
            elevationSlider = app.SliderElevation.Value;
            if app.MasterSwitch.Value == "Off"  %Modo manual:
                set_param('Conexion_Inalambrica/Elevation', 'Value', num2str(elevationSlider))
                app.ElevationEditField.Value = elevationSlider;
            end
        end

        % Value changed function: SliderPitch
        function PitchSliderChange(app, event)
            pitchSlider = app.SliderPitch.Value;
            if app.MasterSwitch.Value == "Off"  %Modo manual:
                set_param('Conexion_Inalambrica/Pitch', 'Value', num2str(pitchSlider))
                app.PitchEditField.Value = pitchSlider;
            end
        end

        % Value changed function: ElevationEditField
        function ElevationTextChange(app, event)
            elevationText = app.ElevationEditField.Value;
            if app.MasterSwitch.Value == "Off"  %Modo manual:
                set_param('Conexion_Inalambrica/Elevation', 'Value', num2str(elevationText))
                app.SliderElevation.Value = elevationText;
            end
        end

        % Value changed function: TravelEditField
        function TravelTextChange(app, event)
            travelText = app.TravelEditField.Value;
            if app.MasterSwitch.Value == "Off"  %Modo manual:
                set_param('Conexion_Inalambrica/Travel', 'Value', num2str(travelText))
                %Aqui no hay slider
            end
        end

        % Value changed function: PitchEditField
        function PitchTextChange(app, event)
            pitchText = app.PitchEditField.Value;
            if app.MasterSwitch.Value == "Off"  %Modo manual:
                set_param('Conexion_Inalambrica/Pitch', 'Value', num2str(pitchText))
                app.SliderPitch.Value = pitchText;
            end
        end

        % Value changed function: MasterSwitch
        function ModoMyoON(app, event)
            value = app.MasterSwitch.Value;
            if value == "On"
                app.PanelModoManual.Visible = false;
            else
                app.PanelModoManual.Visible = true;
            end
        end

        % Button pushed function: HomeButton
        function Regresar_Inicio(app, event)
            Pantalla_Inicio;
            app.UIFigureCloseRequest;
        end

        % Value changed function: ATERRIZARButton
        function AterrizaManual(app, event)
            LAND = app.ATERRIZARButton.Value;
            if LAND  %Aterriza = true:
                set_param('Conexion_Inalambrica/Elevation', 'Value', "-30")
                app.ElevationEditField.Value = -30;
                app.SliderElevation.Value = -30;
                app.aterrizaje = true;  %Utilizo la misma variable
                app.ATERRIZADOLamp.Enable = true;
                %Deshabilitar los de travel y pitch:
                app.TravelEditField.Enable = false;
                app.SliderPitch.Enable = false;
            else
                set_param('Conexion_Inalambrica/Elevation', 'Value', "0")
                app.ElevationEditField.Value = 0;
                app.SliderElevation.Value = 0;
                app.aterrizaje = false;  %Utilizo la misma variable
                app.ATERRIZADOLamp.Enable = false;
                %Habilitar los de travel y pitch:
                app.TravelEditField.Enable = true;
                app.SliderPitch.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'InterfazFondoTransparente-10.png';
            app.PanelModoMyo = uipanel(app.UIFigure);
            app.Orientation = uiaxes(app.PanelModoMyo);
            xlabel(app.Orientation, 'Tiempo [s]')
            ylabel(app.Orientation, 'Orientacion')
            zlabel(app.Orientation, 'Z')
            app.Orientation.XLim = [0 100];
            app.Orientation.YLim = [-90 90];
            app.Orientation.XTick = [0 20 40 60 80 100];
            app.Orientation.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.Orientation.YTick = [-90 -45 0 45 90];
            app.Orientation.YTickLabel = {'-90'; '-45'; '0'; '45'; '90'};
            app.Image5 = uiimage(app.PanelModoMyo);
            app.Image5.ImageSource = 'FondoModoMyoTransparente-11.png';
            app.EspLabel = uilabel(app.PanelModoMyo);
            app.EspLabel.Text = 'Esp:';
            app.PspLabel = uilabel(app.PanelModoMyo);
            app.PspLabel.Text = 'Psp:';
            app.PSP_IMU = uilabel(app.PanelModoMyo);
            app.PSP_IMU.Text = '000.00';
            app.ESP_IMU = uilabel(app.PanelModoMyo);
            app.ESP_IMU.Text = '000.00';
            app.LabelGesto = uilabel(app.PanelModoMyo);
            app.LabelGesto.Text = 'GESTO';
            app.ORIGENButton = uibutton(app.PanelModoMyo, 'push');
            app.ORIGENButton.ButtonPushedFcn = createCallbackFcn(app, @EncerarOrientacion, true);
            app.ORIGENButton.Text = 'ORIGEN';
            app.MODOIMULamp = uilamp(app.PanelModoMyo);
            app.MODOIMULampLabel = uilabel(app.PanelModoMyo);
            app.MODOIMULampLabel.Text = 'MODO IMU';
            app.ImagePinch = uiimage(app.PanelModoMyo);
            app.ImagePinch.Visible = 'off';
            app.ImagePinch.ImageSource = 'Pinch.png';
            app.ImageOpen = uiimage(app.PanelModoMyo);
            app.ImageOpen.Visible = 'off';
            app.ImageOpen.ImageSource = 'Open.png';
            app.ImageFist = uiimage(app.PanelModoMyo);
            app.ImageFist.Visible = 'off';
            app.ImageFist.ImageSource = 'Fist.png';
            app.ImageWaveIn = uiimage(app.PanelModoMyo);
            app.ImageWaveIn.Visible = 'off';
            app.ImageWaveIn.ImageSource = 'WaveIn.png';
            app.ImageWaveOut = uiimage(app.PanelModoMyo);
            app.ImageWaveOut.Visible = 'off';
            app.ImageWaveOut.ImageSource = 'WaveOut.png';
            app.Image = uiimage(app.PanelModoMyo);
            app.Image.ImageSource = 'GestosV2.png';
            app.INICIARButton = uibutton(app.UIFigure, 'push');
            app.INICIARButton.ButtonPushedFcn = createCallbackFcn(app, @CorrerLazo, true);
            app.INICIARButton.Text = 'INICIAR';
            app.DETENERButton = uibutton(app.UIFigure, 'push');
            app.DETENERButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerLazo, true);
            app.DETENERButton.Text = 'DETENER';
            app.MODOMYOLampLabel = uilabel(app.UIFigure);
            app.MODOMYOLampLabel.Text = 'MODO MYO';
            app.MODOMYOLamp = uilamp(app.UIFigure);
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Regresar_Inicio, true);
            app.HomeButton.Icon = 'HomeLogo-07.png';
            app.HomeButton.Text = '';
            app.PitchGaugeLabel = uilabel(app.UIFigure);
            app.PitchGaugeLabel.Text = 'PITCH';
            app.PitchGauge = uigauge(app.UIFigure, 'circular');
            app.PitchGauge.Limits = [-50 50];
            app.PitchGauge.ScaleDirection = 'counterclockwise';
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = '000.00';
            app.TravelGauge = uigauge(app.UIFigure, 'circular');
            app.TravelGauge.Limits = [-150 150];
            app.TravelGauge.ScaleDirection = 'counterclockwise';
            app.TravelGaugeLabel = uilabel(app.UIFigure);
            app.TravelGaugeLabel.Text = 'TRAVEL';
            app.TravelLabel = uilabel(app.UIFigure);
            app.TravelLabel.Text = '000.00';
            app.ElevationGauge = uigauge(app.UIFigure, 'circular');
            app.ElevationGauge.Limits = [-30 30];
            app.ElevationGauge.ScaleDirection = 'counterclockwise';
            app.ElevationGaugeLabel = uilabel(app.UIFigure);
            app.ElevationGaugeLabel.Text = 'ELEVATION';
            app.ElevationLabel = uilabel(app.UIFigure);
            app.ElevationLabel.Text = '000.00';
            app.GRFICASVARIABLESButton = uibutton(app.UIFigure, 'push');
            app.GRFICASVARIABLESButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayVariables, true);
            app.GRFICASVARIABLESButton.Text = {'GRÁFICAS'; ''; 'VARIABLES'};
            app.GRFICASACCIONESDECONTROLButton = uibutton(app.UIFigure, 'push');
            app.GRFICASACCIONESDECONTROLButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayControl, true);
            app.GRFICASACCIONESDECONTROLButton.Text = {'GRÁFICAS'; ''; 'ACCIONES DE'; 'CONTROL'};
            app.TSP_Global = uilabel(app.UIFigure);
            app.TSP_Global.Text = '000.00';
            app.PSP_Global = uilabel(app.UIFigure);
            app.PSP_Global.Text = '000.00';
            app.ESP_Global = uilabel(app.UIFigure);
            app.ESP_Global.Text = '000.00';
            app.SETPOINTSLabel = uilabel(app.UIFigure);
            app.SETPOINTSLabel.Text = 'SETPOINTS';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = '3DOF Frontal.PNG';
            app.Titulo = uilabel(app.UIFigure);
            app.Titulo.Text = {'Diseño e implementación de una plataforma multi-rotor '; 'de 3-GDL comandada por medio de gestos realizados '; 'con la mano'};
            app.MotorFGauge = uigauge(app.UIFigure, 'linear');
            app.MotorBGauge = uigauge(app.UIFigure, 'linear');
            app.MotorBGaugeLabel = uilabel(app.UIFigure);
            app.MotorBGaugeLabel.Text = 'Motor B';
            app.MotorFGaugeLabel = uilabel(app.UIFigure);
            app.MotorFGaugeLabel.Text = 'Motor F';
            app.MasterSwitch = uiswitch(app.UIFigure, 'slider');
            app.MasterSwitch.ValueChangedFcn = createCallbackFcn(app, @ModoMyoON, true);
            app.PanelModoManual = uipanel(app.UIFigure);
            app.Image7 = uiimage(app.PanelModoManual);
            app.Image7.ImageSource = 'FondoModoManual-14.png';
            app.SliderElevation = uislider(app.PanelModoManual);
            app.SliderElevation.Limits = [-30 30];
            app.SliderElevation.ValueChangedFcn = createCallbackFcn(app, @ElevationSliderChange, true);
            app.ElevationEditFieldLabel = uilabel(app.PanelModoManual);
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.ElevationEditField = uieditfield(app.PanelModoManual, 'numeric');
            app.ElevationEditField.Limits = [-30 30];
            app.ElevationEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationTextChange, true);
            app.TravelEditFieldLabel = uilabel(app.PanelModoManual);
            app.TravelEditFieldLabel.Text = 'Travel';
            app.TravelEditField = uieditfield(app.PanelModoManual, 'numeric');
            app.TravelEditField.ValueChangedFcn = createCallbackFcn(app, @TravelTextChange, true);
            app.ModoSwitchLabel = uilabel(app.PanelModoManual);
            app.ModoSwitchLabel.Text = 'Modo';
            app.ModoSwitch = uiswitch(app.PanelModoManual, 'slider');
            app.ModoSwitch.Items = {'Control Travel', 'Control Pitch'};
            app.ModoSwitch.ValueChangedFcn = createCallbackFcn(app, @CambioModoEnManual, true);
            app.ModoSwitch.Value = 'Control Travel';
            app.SliderPitch = uislider(app.PanelModoManual);
            app.SliderPitch.Limits = [-20 20];
            app.SliderPitch.ValueChangedFcn = createCallbackFcn(app, @PitchSliderChange, true);
            app.SliderPitch.Enable = 'off';
            app.pitchLabel = uilabel(app.PanelModoManual);
            app.pitchLabel.Enable = 'off';
            app.pitchLabel.Text = 'pitch';
            app.PitchEditField = uieditfield(app.PanelModoManual, 'numeric');
            app.PitchEditField.Limits = [-20 20];
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchTextChange, true);
            app.PitchEditField.Enable = 'off';
            app.ATERRIZARButton = uibutton(app.PanelModoManual, 'state');
            app.ATERRIZARButton.ValueChangedFcn = createCallbackFcn(app, @AterrizaManual, true);
            app.ATERRIZARButton.Text = 'ATERRIZAR';
            app.ATERRIZADOLampLabel = uilabel(app.PanelModoManual);
            app.ATERRIZADOLampLabel.Text = 'ATERRIZADO';
            app.ATERRIZADOLamp = uilamp(app.PanelModoManual);
            app.ATERRIZADOLamp.Enable = 'off';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'R2SoftShadow.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INTERFAZ_V6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end