classdef Pantalla_Inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        HGRLamp               matlab.ui.control.Lamp
        MyoHGREncendidoLabel  matlab.ui.control.Label
        SimulinkLamp          matlab.ui.control.Lamp
        ConexinInalmbricaSimulinkLampLabel  matlab.ui.control.Label
        INFOButton            matlab.ui.control.Button
        CERRARButton          matlab.ui.control.Button
        ARRANCARButton        matlab.ui.control.Button
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        u       %Puerto UDP para mandar datos desde el otro matlab
        boton   %Variable que indica que el boton fue aplastado
    end
    
    methods (Access = private)
        function poll_Habilitar(app)
            while ~app.boton
                %Averiguo UDP (HGR):
                if app.u.NumBytesAvailable  %Si recibo datos en el puerto UDP esta funcionando el HGR
                    HGR_OK = true;  
                    app.HGRLamp.Enable = true;
                else
                    HGR_OK = false;
                    app.HGRLamp.Enable = false;
                end
                %Averiguo Simulink:
                if get_param('Conexion_Inalambrica', 'SimulationStatus') == "running"
                    SIM_OK = true;
                    app.SimulinkLamp.Enable = true;
                else
                    SIM_OK = false;
                    app.SimulinkLamp.Enable = false;
                end
                %Finalmente habilito el boton:
                if HGR_OK && SIM_OK
                    app.ARRANCARButton.Enable = true;
                else
                    app.ARRANCARButton.Enable = false;
                end
                pause(0.05)
            end
            %Borrar la app
            %La app se borra luego de cortar el while
            app.UIFigureCloseRequest;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ARRANCARButton.Enable = false;
            app.u = udpport('LocalHost','127.0.0.1','LocalPort', 2022); %Declaracion del puerto UDP 2022 es para la pantalla de inicio
            %Cargar el Simulink
            load_system('Conexion_Inalambrica.slx')
            %Luego de inicializar averiguo si esta listo
            app.boton = false;  %No se ha iniciado la interfaz (presionado el boton)
            app.poll_Habilitar;
        end

        % Button pushed function: CERRARButton
        function Cerrar(app, event)
            app.boton = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            flush(app.u)
            clear app.u
            delete(app)
        end

        % Button pushed function: ARRANCARButton
        function START_APP(app, event)
            app.boton = true;
            %Vaciar y borrar el puerto udp
            flush(app.u)
            clear app.u
            %Pausar la simuliacion de Simulink:
            set_param('Conexion_Inalambrica', 'SimulationCommand', 'Pause')
            %Abrir la interfaz
            INTERFAZ_V6
            app.UIFigureCloseRequest;
        end

        % Button pushed function: INFOButton
        function INFO(app, event)
            Pantalla_Informacion
            app.boton = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'InicioTempV2-04.png';
            app.ARRANCARButton = uibutton(app.UIFigure, 'push');
            app.ARRANCARButton.ButtonPushedFcn = createCallbackFcn(app, @START_APP, true);
            app.ARRANCARButton.Text = {'ARRANCAR'; 'APLICACIÓN'};
            app.CERRARButton = uibutton(app.UIFigure, 'push');
            app.CERRARButton.ButtonPushedFcn = createCallbackFcn(app, @Cerrar, true);
            app.CERRARButton.Icon = 'CerrarBoton2-06.png';
            app.CERRARButton.Text = 'CERRAR';
            app.INFOButton = uibutton(app.UIFigure, 'push');
            app.INFOButton.ButtonPushedFcn = createCallbackFcn(app, @INFO, true);
            app.INFOButton.Icon = 'BotonInfo-05.png';
            app.INFOButton.Text = 'INFORMACIÓN';
            app.ConexinInalmbricaSimulinkLampLabel = uilabel(app.UIFigure);
            app.ConexinInalmbricaSimulinkLampLabel.Text = {'Conexión Inalámbrica'; '(Simulink)'};
            app.SimulinkLamp = uilamp(app.UIFigure);
            app.MyoHGREncendidoLabel = uilabel(app.UIFigure);
            app.MyoHGREncendidoLabel.Text = 'Myo HGR Encendido';
            app.HGRLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pantalla_Inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end